classdef ProjectPPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GiithiunhmTab                   matlab.ui.container.Tab
        MNTHCHNHPHNGPHPTNHLabel         matlab.ui.control.Label
        DTV_CLC3Label                   matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MSSVLabel                       matlab.ui.control.Label
        BoCoLabel                       matlab.ui.control.Label
        BoCoWordLabel_2                 matlab.ui.control.Label
        oHmLabel_2                      matlab.ui.control.Label
        TngHpAppLabel                   matlab.ui.control.Label
        BoCoWordLabel                   matlab.ui.control.Label
        TchPhnLabel                     matlab.ui.control.Label
        oHmLabel                        matlab.ui.control.Label
        HiQuyLabel                      matlab.ui.control.Label
        NiSuyLabel                      matlab.ui.control.Label
        TmNghimLabel                    matlab.ui.control.Label
        ChLabel                         matlab.ui.control.Label
        HunhThnhtLabel                  matlab.ui.control.Label
        NgThMHnhLabel                   matlab.ui.control.Label
        TrnngDngLabel                   matlab.ui.control.Label
        DngThQunhNhiLabel               matlab.ui.control.Label
        PhngMinhHongLabel               matlab.ui.control.Label
        mNguynNhNgcLabel                matlab.ui.control.Label
        ThnhVinLabel                    matlab.ui.control.Label
        BOCONCUIKLabel                  matlab.ui.control.Label
        NghimTab                        matlab.ui.container.Tab
        n_N                             matlab.ui.control.TextArea
        SvnglpTextAreaLabel             matlab.ui.control.Label
        ss_N                            matlab.ui.control.NumericEditField
        x0FieldEditFieldLabel           matlab.ui.control.Label
        b_N                             matlab.ui.control.NumericEditField
        bFieldEditFieldLabel            matlab.ui.control.Label
        a_N                             matlab.ui.control.NumericEditField
        aFieldEditFieldLabel            matlab.ui.control.Label
        F_N                             matlab.ui.control.EditField
        Hmfx0Label                      matlab.ui.control.Label
        Output_N                        matlab.ui.control.TextArea
        KtquTextAreaLabel               matlab.ui.control.Label
        Plot_N                          matlab.ui.control.Button
        Bisec_N                         matlab.ui.control.Button
        Iter_N                          matlab.ui.control.Button
        Newton_N                        matlab.ui.control.Button
        Axes_N                          matlab.ui.control.UIAxes
        NisuyTab                        matlab.ui.container.Tab
        GtiOut_NS                       matlab.ui.control.EditField
        KtqunisuyEditFieldLabel         matlab.ui.control.Label
        Gtri_NS                         matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel    matlab.ui.control.Label
        DTNS_NS                         matlab.ui.control.EditField
        athcnisuyEditFieldLabel         matlab.ui.control.Label
        PP_NS                           matlab.ui.control.DropDown
        ChnphngphpDropDownLabel         matlab.ui.control.Label
        Yinput_NS                       matlab.ui.control.EditField
        NhpdliuyEditFieldLabel          matlab.ui.control.Label
        Xinput_NS                       matlab.ui.control.EditField
        NhpdliuxEditFieldLabel          matlab.ui.control.Label
        Plot_NS                         matlab.ui.control.Button
        Dathuc_NS                       matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        HiquyTab                        matlab.ui.container.Tab
        Dropdown_HQ                     matlab.ui.control.DropDown
        LoihiquyDropDownLabel           matlab.ui.control.Label
        GtriIn_HQ                       matlab.ui.control.NumericEditField
        GitricndonEditFieldLabel        matlab.ui.control.Label
        Yinput_HQ                       matlab.ui.control.EditField
        NhpYEditFieldLabel              matlab.ui.control.Label
        Xinput_HQ                       matlab.ui.control.EditField
        NhpXEditFieldLabel              matlab.ui.control.Label
        Run_HQ                          matlab.ui.control.Button
        GtriOut_HQ                      matlab.ui.control.NumericEditField
        GitrdonEditFieldLabel           matlab.ui.control.Label
        Output_HQ                       matlab.ui.control.EditField
        OutputLabel                     matlab.ui.control.Label
        HiquyLabel                      matlab.ui.control.Label
        Axes_HQ                         matlab.ui.control.UIAxes
        ohmTab                          matlab.ui.container.Tab
        Output_DH                       matlab.ui.control.NumericEditField
        KTQULabel                       matlab.ui.control.Label
        run_DH                          matlab.ui.control.Button
        ss                              matlab.ui.container.ButtonGroup
        Oh2_DH                          matlab.ui.control.RadioButton
        Oh_DH                           matlab.ui.control.RadioButton
        Value_DH                        matlab.ui.control.NumericEditField
        GitrcntnhohmEditFieldLabel      matlab.ui.control.Label
        funct                           matlab.ui.control.EditField
        NhpHmEditFieldLabel             matlab.ui.control.Label
        mode                            matlab.ui.container.ButtonGroup
        Ham                             matlab.ui.control.RadioButton
        Mang                            matlab.ui.control.RadioButton
        Y_Val_DH                        matlab.ui.control.EditField
        NhpdliuYEditFieldLabel          matlab.ui.control.Label
        X_Val_DH                        matlab.ui.control.EditField
        NhpdliuXEditFieldLabel          matlab.ui.control.Label
        h_DH                            matlab.ui.control.NumericEditField
        NhpbchEditFieldLabel            matlab.ui.control.Label
        PP_DH                           matlab.ui.control.DropDown
        PhngPhpDropDownLabel            matlab.ui.control.Label
        TNHOHMLabel                     matlab.ui.control.Label
        TchphnTab                       matlab.ui.container.Tab
        N_TP                            matlab.ui.control.EditField
        NhpsonchiaNEditFieldLabel       matlab.ui.control.Label
        Output_TP                       matlab.ui.control.EditField
        KtquaEditFieldLabel             matlab.ui.control.Label
        Can_TP                          matlab.ui.control.EditField
        NhpcntinhtichphnEditFieldLabel  matlab.ui.control.Label
        Ham_TP                          matlab.ui.control.EditField
        NhphamsEditFieldLabel           matlab.ui.control.Label
        Yinput_TP                       matlab.ui.control.EditField
        NhpdliuyEditField_2Label        matlab.ui.control.Label
        Xinput_TP                       matlab.ui.control.EditField
        NhpdliuxEditField_2Label        matlab.ui.control.Label
        khisdungpphinhthangthiNphailasnguyndngLabel  matlab.ui.control.Label
        khisdungppsimpson13thiNphaichnLabel  matlab.ui.control.Label
        khisdungppsimpson38thiNphaichiahtcho3Label  matlab.ui.control.Label
        Simpson38_TP                    matlab.ui.control.Button
        Simpson13_TP                    matlab.ui.control.Button
        Hinhthang_TP                    matlab.ui.control.Button
        HocLabel                        matlab.ui.control.Label
    end

    
    methods (Access = private)
function result = Lagrange(app,xa,ya,x)
    n = length(xa);
    s = 0;

    for i = 1:n
        product = ya(i);
        for j = 1:n
            if i ~= j
                product = product * (x - xa(j)) / (xa(i) - xa(j));
            end
        end
        s = s + product;
    end
    result = s;
end

%Hàm Newton
function y0 = NewtonInterpolation(app, x, y, x0)
    n = length(x);
        % Tính bảng tỷ hiệu (Divided Differences)
    F = zeros(n, n);
    F(:,1) = y(:);
    for j = 2:n
        for i = 1:(n - j + 1)
            F(i,j) = (F(i+1,j-1) - F(i,j-1)) / (x(i+j-1) - x(i));
        end
    end
    % Tính giá trị tại x0
    y0 = F(1,1);
    p = 1;
    for j = 1:n-1
        p = p * (x0 - x(j));
        y0 = y0 + F(1, j+1) * p;
    end
end

function [x, y, mode] = getData(app)

    hasFunction = ~isempty(app.Ham_TP.Value);   
    hasXY = ~isempty(app.Xinput_TP.Value) && ~isempty(app.Yinput_TP.Value);

    % ===== TRƯỜNG HỢP 1: NHẬP HÀM SỐ =====
    if hasFunction
        mode = 'func';
    % Lấy hàm f(x)
    try
        f = str2func(['@(x)', app.Ham_TP.Value]);
    catch
        app.Output_TP.Value = 'Hàm nhập không hợp lệ!';
        x = []; y = [];
        return;
    end

    % Lấy a, b từ chuỗi nhập
    c = str2num(app.Can_TP.Value);
    if numel(c) ~= 2
        app.Output_TP.Value = 'Cận tích phân phải gồm 2 số!';
        x = []; y = [];
        return;
    end
    a = c(1);
    b = c(2);

    % Lấy N
    N = str2double(app.N_TP.Value);
    if isnan(N) || N <= 0 || floor(N)~=N
        app.Output_TP.Value = 'N phải là số nguyên dương!';
        x = []; y = [];
        return;
    end 
    

    % Tạo dữ liệu
    x = linspace(a, b, N+1);
    y = f(x);
    return;
    end

    % ===== TRƯỜNG HỢP 2: NHẬP DỮ LIỆU X-Y =====
    if hasXY
        mode = 'data';

        try
            x = str2num(app.Xinput_TP.Value); %#ok<ST2NM>
            y = str2num(app.Yinput_TP.Value); %#ok<ST2NM>
        catch
            app.Output_TP.Value = 'Dữ liệu x hoặc y không hợp lệ!';
            x = []; y = [];
            return;
        end

        if length(x) ~= length(y)
            app.Output_TP.Value = 'x và y phải có cùng số phần tử!';
            x = []; y = [];
            return;
        end

        return;
    end

    app.Output_TP.Value = 'Chưa nhập dữ liệu hoặc hàm!';
    x = []; y = [];
    mode = '';
end
        
       % Simpson 1/3
function I = simpson13(~, x, y)
    n = length(x) - 1;
    if mod(n, 2) ~= 0
        error('Simpson 1/3 yêu cầu n chẵn');
    end
    h = (x(end) - x(1)) / n;
    I = y(1) + y(end) + 4*sum(y(2:2:end-1)) + 2*sum(y(3:2:end-2));
    I = I * h / 3;
end

        % Simpson 3/8
function I = simpson38(~, x, y)
    n = length(x) - 1;
    if mod(n,3) ~= 0
        error('Simpson 3/8 yêu cầu n chia hết cho 3');
    end
    h = (x(end) - x(1)) / n;
    I = y(1) + y(end);
    for i = 2:n
        if mod(i-1,3)==0
            I = I + 2*y(i);
        else
            I = I + 3*y(i);
        end
    end
    I = I * 3 * h / 8;
end

function [a1, a0, r2] = Hoiquytuyentinh(~,x, y)
    n = length(x);
    sumx = 0;
    sumy = 0;
    sumxy = 0;
    sumx2 = 0;
    st = 0;
    sr = 0;

    for i = 1:n
        sumx = sumx + x(i);
        sumy = sumy + y(i);
        sumxy = sumxy + x(i) * y(i);
        sumx2 = sumx2 + x(i) * x(i);
    end

    xm = sumx/n;
    ym = sumy/n;

    a1 = (n * sumxy - sumx * sumy)/(n * sumx2 - sumx * sumx);
    a0 = ym - a1 * xm;

    for i = 1:n
        st = st + (y(i) - ym)^2;
        sr = sr + (y(i) - a1 * x(i) - a0)^2;
    end
    r2 = (st - sr)/st;
end

function [fx, fx_str, X, Y] = Tuyentinh(~,x,y)
    [a1, a0, r2] = app.Hoiquytuyentinh(app, x, y); 
    X = x;
    Y = a0 + a1 * x; 
    fx = @(x) a0 + a1 .* x;
    fx_str = sprintf("y = %.4f + %.4f*x", a0, a1);
end

function [fx, fx_str, X, Y] = Mu(~, x, y)
    x_log = log10(x);
    y_log = log10(y);

    [a1, a0, r2] = app.Hoiquytuyentinh( x_log, y_log);

    a = 10^a0;
    b = a1;
    fx = @(x) a .* x .^ b;
    fx_str = sprintf('y = %.4f * x^{%.4f}', a, b);
    X = linspace(min(x), max(x), 100);
    Y = a * (X .^ b);
end

function [fx, fx_str, X, Y] = Logarit(~, x, y)
    x_lin = 1./ x;
    y_lin = 1./ y;

    [a1, a0, r2] = app.Hoiquytuyentinh(x_lin, y_lin);

    a = 1 / a0;
    b = a1 * a;
    fx = @(x) a .*x ./(b+x);
    X = linspace(min(x), max(x), 200);
    Y = fx(X);
    fx_str = sprintf("%.4f * ( x /( %.4f + x ))", a, b);
end

end            
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Run_HQ
        function Run_HQPushed(app, event)
            x = str2num(app.Xinput_HQ.Value);
            y = str2num(app.Yinput_HQ.Value);
            choice = app.Dropdown_HQ.Value;
            x_val = app.GtriIn_HQ.Value;
            if length(x) ~= length(y)
                choice = "Error";
            end
            switch choice 
                case "Tuyến tính" 
                [fx, fx_str, X, Y] = Tuyentinh(x, y); 
                title(app.Axes_HQ, "Hồi quy tuyến tính");
                case "Mũ"
                [fx, fx_str, X, Y] = Mu(x,y);
                title(app.Axes_HQ, "Hồi quy mũ");
                case "Logarit"
                [fx, fx_str, X, Y] = Logarit(x, y);
                title(app.Axes_HQ, "Hồi quy logarit");
                case "Error"
                fx_str = sprintf("X và Y phải có cùng số phần tử");
            end
            app.Output_HQ.Value = fx_str;
            if choice ~= "Error"
                plot(app.Axes_HQ, X, Y, 'r', x, y, 'o');
                app.GtriOut_HQ.Value = fx(x_val);
            end
        end

        % Button pushed function: Plot_N
        function Plot_NPushed(app, event)
              f = str2func(['@(x)' app.F_N.Value]);
    a = app.a_N.Value;
    b = app.b_N.Value;

    x = linspace(a,b,300);
    y = arrayfun(f,x);

    plot(app.Axes_N,x,y,'LineWidth',2);
    grid(app.Axes_N,'on');
    yline(app.Axes_N,0,'r');
        end

        % Button pushed function: Bisec_N
        function Bisec_NPushed(app, event)
                f = str2func(['@(x)' app.F_N.Value]);
                a = app.a_N.Value;
                b = app.b_N.Value;
                tol = 1e-6; max_iter = 100;
                
                iter = 0;
                while (b - a) / 2 > tol && iter < max_iter
                    c = (a + b) / 2;
                    if f(c) == 0, break; end
                    if f(a) * f(c) < 0, b = c; else, a = c; end
                    iter = iter + 1;
                end
                
                % Cập nhật kết quả dạng Cell Array
                app.Output_N.Value = {num2str((a + b) / 2, '%.6f')};
                app.n_N.Value = {num2str(iter)};
        end

        % Button pushed function: Iter_N
        function Iter_NPushed(app, event)
        f = str2func(['@(x)' app.F_N.Value]);
    x0 = app.a_N.Value;
    tol = app.ss_N.Value;

    lambda = 0.1;     % có thể chỉnh tay
    g = @(x) x - lambda*f(x);

    iter = 0;
    max_iter = 100;
    err = inf;

    while err > tol && iter < max_iter
        x1 = g(x0);
        err = abs(x1 - x0);
        x0 = x1;
        iter = iter + 1;
    end

    app.Output_N.Value  = {num2str(x0,'%.6f')};
    app.n_N.Value = {num2str(iter)};
        end

        % Button pushed function: Newton_N
        function Newton_NPushed(app, event)
           f = str2func(['@(x)' app.F_N.Value]);

    % Dữ liệu nhập
    a   = app.a_N.Value;
    b   = app.b_N.Value;
    x   = (a + b)/2;                 % x0 tự chọn
    tol = app.ss_N.Value;

    h = 1e-6;                        % bước đạo hàm số
    max_iter = 100;
    iter = 0;

    while iter < max_iter
        fx = f(x);

        % đạo hàm số
        dfx = (f(x + h) - f(x - h)) / (2*h);

        if abs(dfx) < 1e-10
            app.Output_N.Value   = {'Lỗi: f''(x) ≈ 0'};
            app.n_N.Value = {'-'};
            return;
        end

        x_new = x - fx / dfx;

        if abs(x_new - x) < tol
            x = x_new;
            break;
        end

        x = x_new;
        iter = iter + 1;
    end

    % Xuất kết quả
    app.Output_N.Value   = {num2str(x,'%.8f')};
    app.n_N.Value = {num2str(iter)};
        end

        % Button pushed function: Dathuc_NS
        function Dathuc_NSPushed(app, event)
                      x = str2num(app.Xinput_NS.Value);
    y = str2num(app.Yinput_NS.Value);
    x0 = app.Gtri_NS.Value;
    method = app.PP_NS.Value;

    if isempty(x) || isempty(y) || isempty(x0)
        uialert(app.UIFigure, 'Vui lòng nhập đầy đủ dữ liệu X, Y và giá trị cần nội suy!', 'Thiếu dữ liệu');
        return;
    end

    % 2. Tính toán giá trị nội suy y0
    if strcmp(method, 'Lagrange')
        y0 = app.Lagrange(x, y, x0);
    else
        y0 = app.NewtonInterpolation(x, y, x0);
    end

    % 3. Hiển thị kết quả số (làm tròn 3 chữ số cho đẹp)
    app.GtiOut_NS.Value = sprintf('%.3f', y0);

    % 4. Hiển thị biểu thức đa thức
    p = polyfit(x, y, length(x)-1);
    txt = ''; n = length(p);
    for i = 1:n
        coeff = p(i); pow = n - i;
        if abs(coeff) < 1e-4, continue; end
        if coeff > 0 && ~isempty(txt), txt = [txt ' + '];
        elseif coeff < 0, txt = [txt ' - ']; coeff = abs(coeff); end
        if pow > 1, txt = sprintf('%s%.3fx^%d', txt, coeff, pow);
        elseif pow == 1, txt = sprintf('%s%.3fx', txt, coeff);
        else, txt = sprintf('%s%.3f', txt, coeff); end
    end
    app.DTNS_NS.Value = txt;

    % 5. TỰ ĐỘNG CẬP NHẬT ĐỒ THỊ VÀ VẼ ĐIỂM NỘI SUY
    app.Plot_NSPushed(event); % Gọi hàm vẽ lại đường cong
    hold(app.UIAxes, 'on');
    % Vẽ điểm nội suy bằng hình vuông màu đen (ks)
    plot(app.UIAxes, x0, y0, 'ks', 'MarkerSize', 10, 'LineWidth', 2, 'DisplayName', 'Điểm nội suy');
    hold(app.UIAxes, 'off');
        end

        % Button pushed function: Plot_NS
        function Plot_NSPushed(app, event)
                        x = str2num(app.Xinput_NS.Value);
            y = str2num(app.Yinput_NS.Value);
            method = app.PP_NS.Value;

            if isempty(x) || isempty(y), return; end 

            % Tạo tập điểm mịn để vẽ
            x_plot = linspace(min(x), max(x), 100);
            y_plot = zeros(size(x_plot));

            for i = 1:length(x_plot)
                if strcmp(method, 'Lagrange')
                    y_plot(i) = app.Lagrange(x, y, x_plot(i));
                else
                    y_plot(i) = app.NewtonInterpolation(x, y, x_plot(i));
                end
            end

            % Vẽ lên đồ thị
            cla(app.UIAxes); 
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x, y, 'ro', 'MarkerFaceColor', 'r', 'DisplayName', 'Dữ liệu gốc');
            plot(app.UIAxes, x_plot, y_plot, 'b-', 'LineWidth', 1.5, 'DisplayName', 'Đa thức nội suy');
            grid(app.UIAxes, 'on');
            legend(app.UIAxes, 'show');
            hold(app.UIAxes, 'off');
        end

        % Button pushed function: run_DH
        function run_DHPushed(app, event)
        try
  
        Mode_select = app.mode.SelectedObject.Text;
        f_str = app.funct.Value;      
        x_calc = app.Value_DH.Value; 
        ah = app.h_DH.Value;                 
        method = app.PP_DH.Value;
        Saiso = app.ss.SelectedObject.Text;
        result = NaN; 
      
        if strcmp(Mode_select, 'Dùng Hàm')
            f = str2func(['@(x)', f_str]);
            
            switch method
                case 'Tiến'
                    if strcmp(Saiso, 'Oh')
                        result = (f(x_calc + ah) - f(x_calc))/ah;
                    else
                        result = (-f(x_calc + 2*ah) + 4*f(x_calc + ah) - 3*f(x_calc))/(2*ah);
                    end
                  
                case 'Lùi'
                     if strcmp(Saiso, 'Oh')
                        result = (f(x_calc) - f(x_calc - ah)) / ah;
                     else
                        result = (3*f(x_calc) - 4*f(x_calc - ah) + f(x_calc - 2*ah))/(2*ah);
                     end

                case 'Trung tâm'
                     result = (f(x_calc + ah) - f(x_calc - ah))/(2 * ah);
            end
            
        else         
            X = str2num(app.X_Val_DH.Value);
            Y = str2num(app.Y_Val_DH.Value);
            
            idx = find(abs(X - x_calc) < 1e-9, 1);
            if isempty(idx)
                uialert(app.UIFigure, 'Không tìm thấy x trong mảng','Lỗi');
                return;
            end
            
            h_val = 1;
            if length(X) > 1, h_val = X(2) - X(1); end

            switch method
                case 'Tiến'
                    if idx < length(X)
                        if strcmp(Saiso, 'Oh')
                            result = (Y(idx+1) - Y(idx)) / (X(idx+1) - X(idx));  
                        else
                           
                            if idx < length(X) - 1
                                result = (-Y(idx+2) + 4*Y(idx+1) - 3*Y(idx))/(2*h_val);  
                            else
                                uialert(app.UIFigure, 'Thiếu dữ liệu phía sau cho O(h^2)','Lỗi'); return;
                            end
                        end

                    else
                        uialert(app.UIFigure, 'Không thể tính tại điểm cuối','Lỗi'); return;
                    end

                case 'Lùi'
                    if idx > 1
                        if strcmp(Saiso, 'Oh')
                            result = (Y(idx) - Y(idx-1)) / (X(idx) - X(idx-1));  
                        else
                           
                            if idx > 2
                                result = (1*Y(idx-2) - 4*Y(idx-1) + 3*Y(idx))/(2*h_val);  
                            else
                                uialert(app.UIFigure, 'Thiếu dữ liệu phía trước cho O(h^2)','Lỗi'); return;
                            end
                        end
                    else
                        uialert(app.UIFigure, 'Không thể tính Lùi tại điểm đầu','Lỗi'); return;
                    end
                   
                case 'Trung tâm'
                    if idx > 1 && idx < length(X)                     
                        result = (Y(idx+1) - Y(idx-1))/(X(idx+1) - X(idx-1));
                    else
                        uialert(app.UIFigure, 'Cần điểm trước và sau cho Trung tâm','Lỗi'); return;
                    end
            end
        end
        
       
        if ~isnan(result)
            app.Output_DH.Value = result; 
        end
    catch ME
        uialert(app.UIFigure, ME.message, 'Lỗi Thực Thi');
            end
        end

        % Button pushed function: Hinhthang_TP
        function Hinhthang_TPPushed(app, event)
        [x, y, mode] = app.getData();

        if isempty(x) || isempty(y)
        %app.Output_TP.Value = 'Lỗi dữ liệu';
        return;
        end

        % Nếu đang dùng hàm → cần N
        if mode == "func"
        N = str2double(app.N_TP.Value);
        if isnan(N) || N <= 0 || floor(N) ~= N
            app.Output_TP.Value = 'N phải là số nguyên dương';
            return;
        end
        end

        result = trapz(x, y);
        app.Output_TP.Value = num2str(result);
        end

        % Button pushed function: Simpson13_TP
        function Simpson13_TPPushed(app, event)
                    [x, y, mode] = app.getData();

        if isempty(x) || isempty(y)
        %app.Output_TP.Value = 'Lỗi dữ liệu';
        return;
        end

        if mode == "func"
        N = str2double(app.N_TP.Value);
        if isnan(N) || N <= 0 || floor(N) ~= N
            app.Output_TP.Value = 'N phải là số chẵn';
            return;
        end
        end

        try
        result = app.simpson13(x, y);
        app.Output_TP.Value = num2str(result);
        catch ME
        app.Output_TP.Value = ME.message;
        end
        end

        % Button pushed function: Simpson38_TP
        function Simpson38_TPPushed(app, event)
                    [x, y, mode] = app.getData();

        if isempty(x) || isempty(y)
        %    app.Output_TP.Value = 'Lỗi dữ liệu';
        return;
        end

        if mode == "func"
            N = str2double(app.N_TP.Value);
        if isnan(N) || N <= 0 || floor(N) ~= N
            app.Output_TP.Value = 'N phải là số chia hết cho 3';
            return;
        end
        end

        try
            result = app.simpson38(x, y);
            app.Output_TP.Value = num2str(result);
        catch ME
            app.Output_TP.Value = ME.message;
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.BOCONCUIKLabel = uilabel(app.GiithiunhmTab);
            app.BOCONCUIKLabel.Text = 'BÁO CÁO ĐỒ ÁN CUỐI KÌ';
            app.ThnhVinLabel = uilabel(app.GiithiunhmTab);
            app.ThnhVinLabel.Text = 'Thành Viên';
            app.mNguynNhNgcLabel = uilabel(app.GiithiunhmTab);
            app.mNguynNhNgcLabel.Text = 'Đàm Nguyễn Như Ngọc';
            app.PhngMinhHongLabel = uilabel(app.GiithiunhmTab);
            app.PhngMinhHongLabel.Text = 'Phùng Minh Hoàng';
            app.DngThQunhNhiLabel = uilabel(app.GiithiunhmTab);
            app.DngThQunhNhiLabel.Text = 'Dương Thị Quỳnh Nhi';
            app.TrnngDngLabel = uilabel(app.GiithiunhmTab);
            app.TrnngDngLabel.Text = 'Trần Đông Dương';
            app.NgThMHnhLabel = uilabel(app.GiithiunhmTab);
            app.NgThMHnhLabel.Text = 'Ngô Thị Mỹ Hạnh';
            app.HunhThnhtLabel = uilabel(app.GiithiunhmTab);
            app.HunhThnhtLabel.Text = 'Huỳnh Thành Đạt';
            app.ChLabel = uilabel(app.GiithiunhmTab);
            app.ChLabel.Text = 'Chủ Đề';
            app.TmNghimLabel = uilabel(app.GiithiunhmTab);
            app.TmNghimLabel.Text = 'Tìm Nghiệm';
            app.NiSuyLabel = uilabel(app.GiithiunhmTab);
            app.NiSuyLabel.Text = 'Nội Suy';
            app.HiQuyLabel = uilabel(app.GiithiunhmTab);
            app.HiQuyLabel.Text = 'Hồi Quy';
            app.oHmLabel = uilabel(app.GiithiunhmTab);
            app.oHmLabel.Text = 'Đạo Hàm';
            app.TchPhnLabel = uilabel(app.GiithiunhmTab);
            app.TchPhnLabel.Text = 'Tích Phân';
            app.BoCoWordLabel = uilabel(app.GiithiunhmTab);
            app.BoCoWordLabel.Text = 'Báo Cáo Word';
            app.TngHpAppLabel = uilabel(app.GiithiunhmTab);
            app.TngHpAppLabel.Text = 'Tổng Hợp App';
            app.oHmLabel_2 = uilabel(app.GiithiunhmTab);
            app.oHmLabel_2.Text = 'Đạo Hàm';
            app.BoCoWordLabel_2 = uilabel(app.GiithiunhmTab);
            app.BoCoWordLabel_2.Text = 'Báo Cáo Word';
            app.BoCoLabel = uilabel(app.GiithiunhmTab);
            app.BoCoLabel.Text = 'Báo Cáo';
            app.MSSVLabel = uilabel(app.GiithiunhmTab);
            app.MSSVLabel.Text = 'MSSV';
            app.Label = uilabel(app.GiithiunhmTab);
            app.Label.Text = '23207087 ';
            app.Label_2 = uilabel(app.GiithiunhmTab);
            app.Label_2.Text = '23207090 ';
            app.Label_3 = uilabel(app.GiithiunhmTab);
            app.Label_3.Text = '23207058 ';
            app.Label_4 = uilabel(app.GiithiunhmTab);
            app.Label_4.Text = '23207049 ';
            app.Label_5 = uilabel(app.GiithiunhmTab);
            app.Label_5.Text = '23207051 ';
            app.Label_6 = uilabel(app.GiithiunhmTab);
            app.Label_6.Text = '23207042 ';
            app.DTV_CLC3Label = uilabel(app.GiithiunhmTab);
            app.DTV_CLC3Label.Text = '23DTV_CLC3';
            app.MNTHCHNHPHNGPHPTNHLabel = uilabel(app.GiithiunhmTab);
            app.MNTHCHNHPHNGPHPTNHLabel.Text = 'MÔN: THỰC HÀNH PHƯƠNG PHÁP TÍNH';
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.Axes_N = uiaxes(app.NghimTab);
            title(app.Axes_N, 'Title')
            xlabel(app.Axes_N, 'X')
            ylabel(app.Axes_N, 'Y')
            zlabel(app.Axes_N, 'Z')
            app.Newton_N = uibutton(app.NghimTab, 'push');
            app.Newton_N.ButtonPushedFcn = createCallbackFcn(app, @Newton_NPushed, true);
            app.Newton_N.Text = 'Newton';
            app.Iter_N = uibutton(app.NghimTab, 'push');
            app.Iter_N.ButtonPushedFcn = createCallbackFcn(app, @Iter_NPushed, true);
            app.Iter_N.Text = 'Iteration';
            app.Bisec_N = uibutton(app.NghimTab, 'push');
            app.Bisec_N.ButtonPushedFcn = createCallbackFcn(app, @Bisec_NPushed, true);
            app.Bisec_N.Text = 'Bisection';
            app.Plot_N = uibutton(app.NghimTab, 'push');
            app.Plot_N.ButtonPushedFcn = createCallbackFcn(app, @Plot_NPushed, true);
            app.Plot_N.Text = 'Vẽ đồ thị';
            app.KtquTextAreaLabel = uilabel(app.NghimTab);
            app.KtquTextAreaLabel.Text = 'Kết quả ';
            app.Output_N = uitextarea(app.NghimTab);
            app.Hmfx0Label = uilabel(app.NghimTab);
            app.Hmfx0Label.Text = {'Hàm f(x)'; ''};
            app.F_N = uieditfield(app.NghimTab, 'text');
            app.F_N.Value = 'x^2 + x';
            app.aFieldEditFieldLabel = uilabel(app.NghimTab);
            app.aFieldEditFieldLabel.Text = 'aField';
            app.a_N = uieditfield(app.NghimTab, 'numeric');
            app.a_N.Value = 1;
            app.bFieldEditFieldLabel = uilabel(app.NghimTab);
            app.bFieldEditFieldLabel.Text = 'bField';
            app.b_N = uieditfield(app.NghimTab, 'numeric');
            app.b_N.Value = 2;
            app.x0FieldEditFieldLabel = uilabel(app.NghimTab);
            app.x0FieldEditFieldLabel.Text = 'x0Field';
            app.ss_N = uieditfield(app.NghimTab, 'numeric');
            app.ss_N.Value = 0.05;
            app.SvnglpTextAreaLabel = uilabel(app.NghimTab);
            app.SvnglpTextAreaLabel.Text = 'Số vòng lặp';
            app.n_N = uitextarea(app.NghimTab);
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes = uiaxes(app.NisuyTab);
            title(app.UIAxes, 'Đồ thị đa thức nội suy và giá trị nội suy')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Dathuc_NS = uibutton(app.NisuyTab, 'push');
            app.Dathuc_NS.ButtonPushedFcn = createCallbackFcn(app, @Dathuc_NSPushed, true);
            app.Dathuc_NS.Text = 'Tìm đa thức nội suy';
            app.Plot_NS = uibutton(app.NisuyTab, 'push');
            app.Plot_NS.ButtonPushedFcn = createCallbackFcn(app, @Plot_NSPushed, true);
            app.Plot_NS.Text = 'Vẽ đồ thị';
            app.NhpdliuxEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.Xinput_NS = uieditfield(app.NisuyTab, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.Yinput_NS = uieditfield(app.NisuyTab, 'text');
            app.ChnphngphpDropDownLabel = uilabel(app.NisuyTab);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.PP_NS = uidropdown(app.NisuyTab);
            app.PP_NS.Items = {'Newton', 'Lagrange'};
            app.PP_NS.Value = 'Newton';
            app.athcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.athcnisuyEditFieldLabel.Text = 'Đa thức nội suy';
            app.DTNS_NS = uieditfield(app.NisuyTab, 'text');
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.Gtri_NS = uieditfield(app.NisuyTab, 'numeric');
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.GtiOut_NS = uieditfield(app.NisuyTab, 'text');
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.Axes_HQ = uiaxes(app.HiquyTab);
            title(app.Axes_HQ, 'Title')
            xlabel(app.Axes_HQ, 'X')
            ylabel(app.Axes_HQ, 'Y')
            zlabel(app.Axes_HQ, 'Z')
            app.HiquyLabel = uilabel(app.HiquyTab);
            app.HiquyLabel.Text = 'Hồi quy';
            app.OutputLabel = uilabel(app.HiquyTab);
            app.OutputLabel.Text = 'Công thức';
            app.Output_HQ = uieditfield(app.HiquyTab, 'text');
            app.GitrdonEditFieldLabel = uilabel(app.HiquyTab);
            app.GitrdonEditFieldLabel.Text = 'Giá trị dự đoán';
            app.GtriOut_HQ = uieditfield(app.HiquyTab, 'numeric');
            app.Run_HQ = uibutton(app.HiquyTab, 'push');
            app.Run_HQ.ButtonPushedFcn = createCallbackFcn(app, @Run_HQPushed, true);
            app.Run_HQ.Text = 'Chạy';
            app.NhpXEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpXEditFieldLabel.Text = 'Nhập X';
            app.Xinput_HQ = uieditfield(app.HiquyTab, 'text');
            app.NhpYEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpYEditFieldLabel.Text = 'Nhập Y';
            app.Yinput_HQ = uieditfield(app.HiquyTab, 'text');
            app.GitricndonEditFieldLabel = uilabel(app.HiquyTab);
            app.GitricndonEditFieldLabel.Text = 'Giá tri cần dự đoán';
            app.GtriIn_HQ = uieditfield(app.HiquyTab, 'numeric');
            app.LoihiquyDropDownLabel = uilabel(app.HiquyTab);
            app.LoihiquyDropDownLabel.Text = 'Loại hồi quy';
            app.Dropdown_HQ = uidropdown(app.HiquyTab);
            app.Dropdown_HQ.Items = {'Tuyến tính', 'Mũ', 'Logarit'};
            app.Dropdown_HQ.Value = 'Tuyến tính';
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.TNHOHMLabel = uilabel(app.ohmTab);
            app.TNHOHMLabel.Text = 'TÍNH ĐẠO HÀM';
            app.PhngPhpDropDownLabel = uilabel(app.ohmTab);
            app.PhngPhpDropDownLabel.Text = 'Phương Pháp';
            app.PP_DH = uidropdown(app.ohmTab);
            app.PP_DH.Items = {'Tiến', 'Lùi', 'Trung tâm'};
            app.PP_DH.Value = 'Tiến';
            app.NhpbchEditFieldLabel = uilabel(app.ohmTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.h_DH = uieditfield(app.ohmTab, 'numeric');
            app.NhpdliuXEditFieldLabel = uilabel(app.ohmTab);
            app.NhpdliuXEditFieldLabel.Text = 'Nhập dữ liệu X';
            app.X_Val_DH = uieditfield(app.ohmTab, 'text');
            app.NhpdliuYEditFieldLabel = uilabel(app.ohmTab);
            app.NhpdliuYEditFieldLabel.Text = 'Nhập dữ liệu Y';
            app.Y_Val_DH = uieditfield(app.ohmTab, 'text');
            app.mode = uibuttongroup(app.ohmTab);
            app.mode.Title = 'Chế Độ';
            app.Mang = uiradiobutton(app.mode);
            app.Mang.Text = 'Dùng Mảng';
            app.Mang.Value = true;
            app.Ham = uiradiobutton(app.mode);
            app.Ham.Text = 'Dùng Hàm';
            app.NhpHmEditFieldLabel = uilabel(app.ohmTab);
            app.NhpHmEditFieldLabel.Text = 'Nhập Hàm';
            app.funct = uieditfield(app.ohmTab, 'text');
            app.GitrcntnhohmEditFieldLabel = uilabel(app.ohmTab);
            app.GitrcntnhohmEditFieldLabel.Text = 'Giá trị cần tính đạo hàm';
            app.Value_DH = uieditfield(app.ohmTab, 'numeric');
            app.ss = uibuttongroup(app.ohmTab);
            app.ss.Title = 'Giá trị sai số';
            app.Oh_DH = uiradiobutton(app.ss);
            app.Oh_DH.Text = 'Oh';
            app.Oh_DH.Value = true;
            app.Oh2_DH = uiradiobutton(app.ss);
            app.Oh2_DH.Text = 'Oh2';
            app.run_DH = uibutton(app.ohmTab, 'push');
            app.run_DH.ButtonPushedFcn = createCallbackFcn(app, @run_DHPushed, true);
            app.run_DH.Text = 'TÍNH';
            app.KTQULabel = uilabel(app.ohmTab);
            app.KTQULabel.Text = 'KẾT QUẢ';
            app.Output_DH = uieditfield(app.ohmTab, 'numeric');
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.HocLabel = uilabel(app.TchphnTab);
            app.HocLabel.Text = 'Hoặc';
            app.Hinhthang_TP = uibutton(app.TchphnTab, 'push');
            app.Hinhthang_TP.ButtonPushedFcn = createCallbackFcn(app, @Hinhthang_TPPushed, true);
            app.Hinhthang_TP.Text = 'PP hình thang';
            app.Simpson13_TP = uibutton(app.TchphnTab, 'push');
            app.Simpson13_TP.ButtonPushedFcn = createCallbackFcn(app, @Simpson13_TPPushed, true);
            app.Simpson13_TP.Text = 'PP simpson 1/3';
            app.Simpson38_TP = uibutton(app.TchphnTab, 'push');
            app.Simpson38_TP.ButtonPushedFcn = createCallbackFcn(app, @Simpson38_TPPushed, true);
            app.Simpson38_TP.Text = 'PP simpson 3/8';
            app.khisdungppsimpson38thiNphaichiahtcho3Label = uilabel(app.TchphnTab);
            app.khisdungppsimpson38thiNphaichiahtcho3Label.Text = {'khi sử dụng pp simpson'; ' 3/8 thì N phải chia '; 'hết cho 3'};
            app.khisdungppsimpson13thiNphaichnLabel = uilabel(app.TchphnTab);
            app.khisdungppsimpson13thiNphaichnLabel.Text = {'khi sử dụng pp simpson'; ' 1/3 thì N phải chẵn'};
            app.khisdungpphinhthangthiNphailasnguyndngLabel = uilabel(app.TchphnTab);
            app.khisdungpphinhthangthiNphailasnguyndngLabel.Text = {'khi sử dụng pp hình '; 'thang thì N phải là số '; 'nguyên dương'};
            app.NhpdliuxEditField_2Label = uilabel(app.TchphnTab);
            app.NhpdliuxEditField_2Label.Text = 'Nhập dữ liệu x ';
            app.Xinput_TP = uieditfield(app.TchphnTab, 'text');
            app.Xinput_TP.Value = '1 2 3 4 5';
            app.NhpdliuyEditField_2Label = uilabel(app.TchphnTab);
            app.NhpdliuyEditField_2Label.Text = 'Nhập dữ liệu y';
            app.Yinput_TP = uieditfield(app.TchphnTab, 'text');
            app.Yinput_TP.Value = '2 3 4 5 8';
            app.NhphamsEditFieldLabel = uilabel(app.TchphnTab);
            app.NhphamsEditFieldLabel.Text = 'Nhập hàm số';
            app.Ham_TP = uieditfield(app.TchphnTab, 'text');
            app.NhpcntinhtichphnEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpcntinhtichphnEditFieldLabel.Text = {'Nhập cận để'; 'tính tích phân '};
            app.Can_TP = uieditfield(app.TchphnTab, 'text');
            app.KtquaEditFieldLabel = uilabel(app.TchphnTab);
            app.KtquaEditFieldLabel.Text = 'Kết quả';
            app.Output_TP = uieditfield(app.TchphnTab, 'text');
            app.NhpsonchiaNEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpsonchiaNEditFieldLabel.Text = 'Nhập số đoạn chia N ';
            app.N_TP = uieditfield(app.TchphnTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectPPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end